classdef H100_Robot_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SpecialMovesPanel          matlab.ui.container.Panel
        TheChudButton              matlab.ui.control.Button
        GripperImage               matlab.ui.control.Image
        BarImage                   matlab.ui.control.Image
        SetTimeDelaysPanel         matlab.ui.container.Panel
        ResetTimeDelaysButton      matlab.ui.control.Button
        SetTimeDelaysButton        matlab.ui.control.Button
        TimeTable                  matlab.ui.control.Table
        PlateLoaderResponsesPanel  matlab.ui.container.Panel
        GetStatusButton            matlab.ui.control.Button
        StatusLabel                matlab.ui.control.Label
        MovePlatePanel             matlab.ui.container.Panel
        ToDropDown                 matlab.ui.control.DropDown
        ToDropDownLabel            matlab.ui.control.Label
        FromDropDown               matlab.ui.control.DropDown
        FromDropDownLabel          matlab.ui.control.Label
        MoveButton                 matlab.ui.control.Button
        GripperPanel               matlab.ui.container.Panel
        GripperCloseButton         matlab.ui.control.Button
        GripperOpenButton          matlab.ui.control.Button
        XAxisPanel                 matlab.ui.container.Panel
        Button_5                   matlab.ui.control.Button
        Button_4                   matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        Button_1                   matlab.ui.control.Button
        ZAxisPanel                 matlab.ui.container.Panel
        DownArrow                  matlab.ui.control.Image
        UpArrow                    matlab.ui.control.Image
        ConnectionPanel            matlab.ui.container.Panel
        COMPortEditField           matlab.ui.control.NumericEditField
        COMPortEditFieldLabel      matlab.ui.control.Label
        DisconnectButton           matlab.ui.control.Button
        ConnectButton              matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        Image_3                    matlab.ui.control.Image
    end


    properties (Access = private)
        Robot
        GripperImageSources
        GripperImageLocations
        BarImageLocations
        xAxisPosition = 3
        isZAxisExtended = 'false'
        isGripperClosed = 'true'
    end

    methods (Access = private)

        function updateGripperUI(app, xAxisPosition, isZAxisExtended, isGripperClosed)
            app.xAxisPosition = xAxisPosition;
            app.isZAxisExtended = isZAxisExtended;
            app.isGripperClosed = isGripperClosed;

            % Upper is just Lower position + 5
            if isZAxisExtended
                Index = xAxisPosition;
                app.BarImage.Visible = "off";
            else
                Index = xAxisPosition + 5;
                app.BarImage.Visible = "on";
            end

            % Moving image
            app.GripperImage.Position = app.GripperImageLocations(Index, :);
            app.BarImage.Position = app.BarImageLocations(xAxisPosition,:);

            % Changing Image
            switch isGripperClosed
                case 'open_no_plate'
                    app.GripperImage.ImageSource = app.GripperImageSources(2);
                case 'with_plate'
                    app.GripperImage.ImageSource = app.GripperImageSources(3);
                case 'closed_no_plate'
                    app.GripperImage.ImageSource = app.GripperImageSources(1);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set default 5x5 matrix values
            defaultTimes = [ 0 60 20 30  0;
                0  0 30 30  0;
                0 30  0 30  0;
                0 30 30  0  0;
                0 30 20 60  0 ];
            app.TimeTable.Data = defaultTimes;
            app.TimeTable.ColumnName = {'1', '2', '3', '4', '5'};
            app.TimeTable.RowName    = {'1', '2', '3', '4', '5'};
            app.TimeTable.ColumnEditable = true;

            app.GripperImageSources = ["gripper_closed_no_plate.jpg","gripper_open_no_plate.jpg","gripper_with_plate.jpg"];
            app.BarImage.Visible = "off";
            % 1-5 - Upper
            % 6-10 - Lower
            app.GripperImageLocations(1,:) = [280,541,100,100];
            app.GripperImageLocations(2,:) = [407,541,100,100];
            app.GripperImageLocations(3,:) = [538,541,100,100];
            app.GripperImageLocations(4,:) = [665,541,100,100];
            app.GripperImageLocations(5,:) = [794,541,100,100];
            app.GripperImageLocations(6,:) = [280,346,100,100];
            app.GripperImageLocations(7,:) = [407,346,100,100];
            app.GripperImageLocations(8,:) = [538,346,100,100];
            app.GripperImageLocations(9,:) = [665,346,100,100];
            app.GripperImageLocations(10,:) = [794,346,100,100];

            app.BarImageLocations(1,:) = [299,389,63,229];
            app.BarImageLocations(2,:) = [427,389,63,229];
            app.BarImageLocations(3,:) = [553,389,63,229];
            app.BarImageLocations(4,:) = [685,389,63,229];
            app.BarImageLocations(5,:) = [814,389,63,229];
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.Robot = PlateLoaderSim(app.COMPortEditField.Value);
            % Fill in PlateLoader(port) with the Text Field's Numerical
            % value
            %app.StatusLabel.Text = string(PlateLoader(app.COMPortEditField.Value));
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.Robot.shutdown(); % Calls the shutdown method of PlateLoader
            app.Robot = [];       % Clear the property
        end

        % Button pushed function: GripperCloseButton
        function GripperCloseButtonPushed(app, event)
            app.StatusLabel.Text = string(app.Robot.close); % Changes Status Label text to output and moved robot
            if contains(lower(string(app.Robot.close)), "noplate")
                app.updateGripperUI(app.xAxisPosition, app.isZAxisExtended, 'closed_no_plate');

            else
                app.updateGripperUI(app.xAxisPosition, app.isZAxisExtended, 'with_plate');
            end

        end

        % Button pushed function: GripperOpenButton
        function GripperOpenButtonPushed(app, event)
            app.StatusLabel.Text = string(app.Robot.open);
            app.updateGripperUI(app.xAxisPosition, app.isZAxisExtended, 'open_no_plate');
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.StatusLabel.Text = string(app.Robot.x(1));
            app.updateGripperUI(1, app.isZAxisExtended, app.isGripperClosed);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.StatusLabel.Text = string(app.Robot.x(2));
            app.updateGripperUI(2, app.isZAxisExtended, app.isGripperClosed);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.StatusLabel.Text = string(app.Robot.x(3));
            app.updateGripperUI(3, app.isZAxisExtended, app.isGripperClosed);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.StatusLabel.Text = string(app.Robot.x(4));
            app.updateGripperUI(4, app.isZAxisExtended, app.isGripperClosed);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.StatusLabel.Text = string(app.Robot.x(5));
            app.updateGripperUI(5, app.isZAxisExtended, app.isGripperClosed);
        end

        % Image clicked function: UpArrow
        function UpArrowImageClicked(app, event)
            app.StatusLabel.Text = string(app.Robot.retract);
            app.updateGripperUI(app.xAxisPosition, true, app.isGripperClosed);
        end

        % Image clicked function: DownArrow
        function DownArrowImageClicked(app, event)
            app.StatusLabel.Text = string(app.Robot.extend);
            app.updateGripperUI(app.xAxisPosition, false, app.isGripperClosed);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.StatusLabel.Text = string(app.Robot.reset);
            app.updateGripperUI(3, true, 'closed_no_plate');
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            app.StatusLabel.Text = string(app.Robot.movePlate(str2double(app.FromDropDown.Value),str2double(app.ToDropDown.Value)));
            app.updateGripperUI(3, false, app.isGripperClosed);
        end

        % Button pushed function: GetStatusButton
        function GetStatusButtonPushed(app, event)
            app.StatusLabel.Text = string(app.Robot.getStatus);
        end

        % Button pushed function: ResetTimeDelaysButton
        function ResetTimeDelaysButtonPushed(app, event)
            app.Robot.resetDefaultTimes
            defaultTimes = [ 0 60 20 30  0;
                0  0 30 30  0;
                0 30  0 30  0;
                0 30 30  0  0;
                0 30 20 60  0 ];
            app.TimeTable.Data = defaultTimes;
        end

        % Button pushed function: SetTimeDelaysButton
        function SetTimeDelaysButtonPushed(app, event)
            app.Robot.setTimeValues(app.TimeTable.Data);

        end

        % Button pushed function: TheChudButton
        function TheChudButtonPushed(app, event)
            app.StatusLabel.Text = string(app.Robot.x(1));
            app.updateGripperUI(1, true, 'closed_no_plate');
            app.StatusLabel.Text = string(app.Robot.open);
            app.updateGripperUI(1, true, 'open_no_plate');
            app.StatusLabel.Text = string(app.Robot.extend);
            app.updateGripperUI(1, false, 'open_no_plate');
            app.StatusLabel.Text = string(app.Robot.close);
            app.updateGripperUI(1, false, 'with_plate');
            app.StatusLabel.Text = string(app.Robot.retract);
            app.updateGripperUI(1, true, 'with_plate');
            app.StatusLabel.Text = string(app.Robot.x(3));
            app.updateGripperUI(3, true, 'with_plate');
            app.StatusLabel.Text = string(app.Robot.extend);
            app.updateGripperUI(3, false, 'with_plate');
            app.StatusLabel.Text = string(app.Robot.retract);
            app.updateGripperUI(3, true, 'with_plate');
            app.StatusLabel.Text = string(app.Robot.x(5));
            app.updateGripperUI(5, true, 'with_plate');
            app.StatusLabel.Text = string(app.Robot.extend);
            app.updateGripperUI(5, false, 'with_plate');
            app.StatusLabel.Text = string(app.Robot.open);
            app.updateGripperUI(5, false, 'open_no_plate');
            app.StatusLabel.Text = string(app.Robot.retract);
            app.updateGripperUI(5, true, 'open_no_plate');
            app.StatusLabel.Text = string(app.Robot.close);
            app.updateGripperUI(5, true, 'closed_no_plate');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'robot_background.jpg');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.COMPortEditFieldLabel = uilabel(app.ConnectionPanel);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.ConnectionPanel, 'numeric');
            app.ZAxisPanel = uipanel(app.UIFigure);
            app.ZAxisPanel.Title = 'Z-Axis';
            app.UpArrow = uiimage(app.ZAxisPanel);
            app.UpArrow.ImageClickedFcn = createCallbackFcn(app, @UpArrowImageClicked, true);
            app.UpArrow.ImageSource = fullfile(pathToMLAPP, 'UpArrow.png');
            app.DownArrow = uiimage(app.ZAxisPanel);
            app.DownArrow.ImageClickedFcn = createCallbackFcn(app, @DownArrowImageClicked, true);
            app.DownArrow.ImageSource = fullfile(pathToMLAPP, 'DownArrow.png');
            app.XAxisPanel = uipanel(app.UIFigure);
            app.XAxisPanel.Title = 'X-Axis';
            app.Button_1 = uibutton(app.XAxisPanel, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.XAxisPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.XAxisPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.XAxisPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.XAxisPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.GripperPanel = uipanel(app.UIFigure);
            app.GripperPanel.Title = 'Gripper';
            app.GripperOpenButton = uibutton(app.GripperPanel, 'push');
            app.GripperOpenButton.ButtonPushedFcn = createCallbackFcn(app, @GripperOpenButtonPushed, true);
            app.GripperOpenButton.Text = 'Gripper Open';
            app.GripperCloseButton = uibutton(app.GripperPanel, 'push');
            app.GripperCloseButton.ButtonPushedFcn = createCallbackFcn(app, @GripperCloseButtonPushed, true);
            app.GripperCloseButton.Text = 'Gripper Close';
            app.MovePlatePanel = uipanel(app.UIFigure);
            app.MovePlatePanel.Title = 'Move Plate';
            app.MoveButton = uibutton(app.MovePlatePanel, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.FromDropDownLabel = uilabel(app.MovePlatePanel);
            app.FromDropDownLabel.Text = 'From:';
            app.FromDropDown = uidropdown(app.MovePlatePanel);
            app.FromDropDown.Items = {'1', '2', '3', '4', '5'};
            app.FromDropDown.Value = '1';
            app.ToDropDownLabel = uilabel(app.MovePlatePanel);
            app.ToDropDownLabel.Text = 'To:';
            app.ToDropDown = uidropdown(app.MovePlatePanel);
            app.ToDropDown.Items = {'1', '2', '3', '4', '5'};
            app.ToDropDown.Value = '1';
            app.PlateLoaderResponsesPanel = uipanel(app.UIFigure);
            app.PlateLoaderResponsesPanel.Title = 'Plate Loader Responses';
            app.StatusLabel = uilabel(app.PlateLoaderResponsesPanel);
            app.StatusLabel.Text = 'Status...';
            app.GetStatusButton = uibutton(app.PlateLoaderResponsesPanel, 'push');
            app.GetStatusButton.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButtonPushed, true);
            app.GetStatusButton.Text = 'Get Status';
            app.SetTimeDelaysPanel = uipanel(app.UIFigure);
            app.SetTimeDelaysPanel.Title = 'Set Time Delays';
            app.TimeTable = uitable(app.SetTimeDelaysPanel);
            app.TimeTable.ColumnName = '';
            app.TimeTable.RowName = {};
            app.TimeTable.ColumnEditable = true;
            app.SetTimeDelaysButton = uibutton(app.SetTimeDelaysPanel, 'push');
            app.SetTimeDelaysButton.ButtonPushedFcn = createCallbackFcn(app, @SetTimeDelaysButtonPushed, true);
            app.SetTimeDelaysButton.Text = 'Set TimeDelays';
            app.ResetTimeDelaysButton = uibutton(app.SetTimeDelaysPanel, 'push');
            app.ResetTimeDelaysButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTimeDelaysButtonPushed, true);
            app.ResetTimeDelaysButton.Text = 'Reset TimeDelays';
            app.BarImage = uiimage(app.UIFigure);
            app.BarImage.ImageSource = fullfile(pathToMLAPP, 'extended_bars.jpg');
            app.GripperImage = uiimage(app.UIFigure);
            app.GripperImage.ImageSource = fullfile(pathToMLAPP, 'gripper_closed_no_plate.jpg');
            app.SpecialMovesPanel = uipanel(app.UIFigure);
            app.SpecialMovesPanel.Title = 'Special Moves';
            app.TheChudButton = uibutton(app.SpecialMovesPanel, 'push');
            app.TheChudButton.ButtonPushedFcn = createCallbackFcn(app, @TheChudButtonPushed, true);
            app.TheChudButton.Text = 'The Chud';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = H100_Robot_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end