classdef Sixty_Robot_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MoveButton             matlab.ui.control.Button
        MoveToEditField        matlab.ui.control.NumericEditField
        MoveFromEditField      matlab.ui.control.NumericEditField
        MoveToLabel            matlab.ui.control.Label
        MoveFromLabel          matlab.ui.control.Label
        XAxisLabel             matlab.ui.control.Label
        ResetButton            matlab.ui.control.Button
        ZAxisLabel             matlab.ui.control.Label
        DownArrow              matlab.ui.control.Image
        UpArrow                matlab.ui.control.Image
        Button_5               matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Button_1               matlab.ui.control.Button
        GripperCloseButton     matlab.ui.control.Button
        DisconnectButton       matlab.ui.control.Button
        GripperOpenButton      matlab.ui.control.Button
        COMPortEditField       matlab.ui.control.NumericEditField
        COMPortEditFieldLabel  matlab.ui.control.Label
        ConnectButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        Robot % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
             app.Robot = PlateLoader(app.COMPortEditField.Value);
             % Fill in PlateLoader(port) with the Text Field's Numerical
             % value
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
           app.Robot.shutdown(); % Calls the shutdown method of PlateLoader
           app.Robot = [];       % Clear the property
        end

        % Button pushed function: GripperCloseButton
        function GripperCloseButtonPushed(app, event)
            app.Robot.close
        end

        % Button pushed function: GripperOpenButton
        function GripperOpenButtonPushed(app, event)
           app.Robot.open
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.Robot.x(1)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Robot.x(2)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Robot.x(3)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Robot.x(4)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Robot.x(5)
        end

        % Callback function
        function ZExtendButtonPushed(app, event)
            app.Robot.extend
        end

        % Callback function
        function ZRetractButtonPushed(app, event)
            app.Robot.retract
        end

        % Image clicked function: UpArrow
        function UpArrowImageClicked(app, event)
            app.Robot.retract
        end

        % Image clicked function: DownArrow
        function DownArrowImageClicked(app, event)
            app.Robot.extend
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Robot.reset
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            app.Robot.movePlate(app.MoveFromEditField.Value,app.MoveToEditField.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.COMPortEditFieldLabel = uilabel(app.UIFigure);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.UIFigure, 'numeric');
            app.GripperOpenButton = uibutton(app.UIFigure, 'push');
            app.GripperOpenButton.ButtonPushedFcn = createCallbackFcn(app, @GripperOpenButtonPushed, true);
            app.GripperOpenButton.Text = 'Gripper Open';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.GripperCloseButton = uibutton(app.UIFigure, 'push');
            app.GripperCloseButton.ButtonPushedFcn = createCallbackFcn(app, @GripperCloseButtonPushed, true);
            app.GripperCloseButton.Text = 'Gripper Close';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.UpArrow = uiimage(app.UIFigure);
            app.UpArrow.ImageClickedFcn = createCallbackFcn(app, @UpArrowImageClicked, true);
            app.UpArrow.ImageSource = fullfile(pathToMLAPP, 'UpArrow.png');
            app.DownArrow = uiimage(app.UIFigure);
            app.DownArrow.ImageClickedFcn = createCallbackFcn(app, @DownArrowImageClicked, true);
            app.DownArrow.ImageSource = fullfile(pathToMLAPP, 'DownArrow.png');
            app.ZAxisLabel = uilabel(app.UIFigure);
            app.ZAxisLabel.Text = 'Z-Axis';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.XAxisLabel = uilabel(app.UIFigure);
            app.XAxisLabel.Text = 'X-Axis';
            app.MoveFromLabel = uilabel(app.UIFigure);
            app.MoveFromLabel.Text = 'Move From ';
            app.MoveToLabel = uilabel(app.UIFigure);
            app.MoveToLabel.Text = 'Move To';
            app.MoveFromEditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveToEditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveButton = uibutton(app.UIFigure, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sixty_Robot_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end