classdef HTML_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GripperImage                matlab.ui.control.Image
        SetTimeDelaysPanel          matlab.ui.container.Panel
        ResetTimeDelaysButton       matlab.ui.control.Button
        SetTimeDelaysButton         matlab.ui.control.Button
        TimeTable                   matlab.ui.control.Table
        PlateLoaderResponsesPanel   matlab.ui.container.Panel
        GetStatusButton             matlab.ui.control.Button
        StatusLabel                 matlab.ui.control.Label
        MovePlatePanel              matlab.ui.container.Panel
        ToDropDown                  matlab.ui.control.DropDown
        ToDropDownLabel             matlab.ui.control.Label
        FromDropDown                matlab.ui.control.DropDown
        FromDropDownLabel           matlab.ui.control.Label
        MoveButton                  matlab.ui.control.Button
        GripperPanel                matlab.ui.container.Panel
        GripperCloseButton          matlab.ui.control.Button
        GripperOpenButton           matlab.ui.control.Button
        XAxisPanel                  matlab.ui.container.Panel
        Button_5                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button_1                    matlab.ui.control.Button
        ZAxisPanel                  matlab.ui.container.Panel
        DownArrow                   matlab.ui.control.Image
        UpArrow                     matlab.ui.control.Image
        ConnectionPanel             matlab.ui.container.Panel
        ServerAddressTextArea       matlab.ui.control.TextArea
        ServerAddressTextAreaLabel  matlab.ui.control.Label
        DisconnectButton            matlab.ui.control.Button
        ConnectButton               matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        Robot
        GripperImageSources
        GripperImageLocations
        xAxisPosition = 3
        isZAxisExtended = false
        isGripperClosed = 'closed_no_plate'
    end

    methods (Access = private)

        function updateGripperUI(app, xAxisPosition, isZAxisExtended, isGripperClosed)
            app.xAxisPosition = xAxisPosition;
            app.isZAxisExtended = isZAxisExtended;
            app.isGripperClosed = isGripperClosed;

            % Upper is just Lower position + 5
            if isZAxisExtended
                Index = xAxisPosition;
            else
                Index = xAxisPosition + 5;
            end

            % Moving image
            app.GripperImage.Position = app.GripperImageLocations(Index, :);

            % Changing Image
            switch isGripperClosed
                case 'open_no_plate'
                    app.GripperImage.ImageSource = app.GripperImageSources(2);
                case 'with_plate'
                    app.GripperImage.ImageSource = app.GripperImageSources(3);
                case 'closed_no_plate'
                    app.GripperImage.ImageSource = app.GripperImageSources(1);
            end
        end


        function ok = isConnected(app)
            ok = ~isempty(app.Robot);
            if ~ok
                app.StatusLabel.Text = "Not connected - press Connect first";
            end
        end

        % Move X axis and refresh the picture
        function moveX(app, pos)
            if ~app.isConnected(), return; end
            app.StatusLabel.Text = string(app.Robot.x(pos));
            app.syncGripperUI();
        end

        % Redraw the gripper picture from the robot object's tracked state
        function syncGripperUI(app)
            [xPos, zAxis, grip, plate] = app.Robot.getProperties();
            if ~grip
                state = 'open_no_plate';
            elseif plate
                state = 'with_plate';
            else
                state = 'closed_no_plate';
            end
            app.updateGripperUI(xPos, zAxis, state);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set default 5x5 matrix values
            app.TimeTable.ColumnName = {'1', '2', '3', '4', '5'};
            app.TimeTable.RowName    = {'1', '2', '3', '4', '5'};
            app.TimeTable.ColumnEditable = true;
            defaultTimes = [ 0 60 20 30  0;
                0  0 30 30  0;
                0 30  0 30  0;
                0 30 30  0  0;
                0 30 20 60  0 ];
            app.TimeTable.Data = defaultTimes;

            app.GripperImageSources = ["gripper_closed_no_plate.jpg","gripper_open_no_plate.jpg","gripper_with_plate.jpg"];
            % 1-5 - Upper
            % 6-10 - Lower
            app.GripperImageLocations(1,:) = [136,342,100,100];
            app.GripperImageLocations(2,:) = [259,342,100,100];
            app.GripperImageLocations(3,:) = [377,342,100,100];
            app.GripperImageLocations(4,:) = [499,342,100,100];
            app.GripperImageLocations(5,:) = [612,342,100,100];
            app.GripperImageLocations(6,:) = [136,535,100,100];
            app.GripperImageLocations(7,:) = [259,535,100,100];
            app.GripperImageLocations(8,:) = [377,535,100,100];
            app.GripperImageLocations(9,:) = [499,535,100,100];
            app.GripperImageLocations(10,:) = [612,535,100,100];
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % TextArea.Value is a cell array of lines -> make one string
            address = strtrim(strjoin(string(app.ServerAddressTextArea.Value), ""));
            if strlength(address) == 0
                app.StatusLabel.Text = "Enter the server address (ip:port)";
                return
            end
            app.StatusLabel.Text = "Connecting to " + address + " ...";
            drawnow;
            robot = HTTPPlateLoader(address);
            status = string(robot.getStatus());
            app.StatusLabel.Text = status;
            if contains(lower(status), "error")
                app.Robot = [];   % server not reachable, stay disconnected
            else
                app.Robot = robot;
                app.syncGripperUI();
            end

        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if ~isempty(app.Robot)
                app.Robot.shutdown();
            end
            app.Robot = [];
            app.StatusLabel.Text = "Disconnected";

        end

        % Button pushed function: GripperCloseButton
        function GripperCloseButtonPushed(app, event)
            if ~app.isConnected(), return; end
            app.StatusLabel.Text = string(app.Robot.close);
            app.syncGripperUI();

        end

        % Button pushed function: GripperOpenButton
        function GripperOpenButtonPushed(app, event)
            if ~app.isConnected(), return; end
            app.StatusLabel.Text = string(app.Robot.open);
            app.syncGripperUI();
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.moveX(1);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.moveX(2);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.moveX(3);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.moveX(4);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.moveX(5);
        end

        % Image clicked function: UpArrow
        function UpArrowImageClicked(app, event)
            if ~app.isConnected(), return; end
            app.StatusLabel.Text = string(app.Robot.retract);
            app.syncGripperUI();
        end

        % Image clicked function: DownArrow
        function DownArrowImageClicked(app, event)
            if ~app.isConnected(), return; end
            app.StatusLabel.Text = string(app.Robot.extend);
            app.syncGripperUI();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if ~app.isConnected(), return; end
            app.StatusLabel.Text = string(app.Robot.reset);
            app.Robot.resetDefaultTimes();
            app.TimeTable.Data = HTTPPlateLoader.defaultTimeTable;
            app.syncGripperUI();
            drawnow;
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            if ~app.isConnected(), return; end
            from = str2double(app.FromDropDown.Value);
            to   = str2double(app.ToDropDown.Value);
            app.StatusLabel.Text = "Moving plate " + from + " to " + to + " ...";
            drawnow;
            app.StatusLabel.Text = string(app.Robot.movePlate(from, to));
            app.syncGripperUI();
        end

        % Button pushed function: GetStatusButton
        function GetStatusButtonPushed(app, event)
            if ~app.isConnected(), return; end
            app.StatusLabel.Text = string(app.Robot.getStatus);
        end

        % Button pushed function: ResetTimeDelaysButton
        function ResetTimeDelaysButtonPushed(app, event)
            if ~app.isConnected(), return; end
            app.Robot.resetDefaultTimes();
            app.TimeTable.Data = HTTPPlateLoader.defaultTimeTable;
            drawnow;
        end

        % Button pushed function: SetTimeDelaysButton
        function SetTimeDelaysButtonPushed(app, event)
            if ~app.isConnected(), return; end
            app.StatusLabel.Text = "Setting time delays ...";
            drawnow;
            app.Robot.setTimeValues(app.TimeTable.Data);
            app.StatusLabel.Text = "Time delays sent";

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ServerAddressTextAreaLabel = uilabel(app.ConnectionPanel);
            app.ServerAddressTextAreaLabel.Text = 'Server Address';
            app.ServerAddressTextArea = uitextarea(app.ConnectionPanel);
            app.ZAxisPanel = uipanel(app.UIFigure);
            app.ZAxisPanel.Title = 'Z-Axis';
            app.UpArrow = uiimage(app.ZAxisPanel);
            app.UpArrow.ImageClickedFcn = createCallbackFcn(app, @UpArrowImageClicked, true);
            app.UpArrow.ImageSource = fullfile(pathToMLAPP, 'UpArrow.png');
            app.DownArrow = uiimage(app.ZAxisPanel);
            app.DownArrow.ImageClickedFcn = createCallbackFcn(app, @DownArrowImageClicked, true);
            app.DownArrow.ImageSource = fullfile(pathToMLAPP, 'DownArrow.png');
            app.XAxisPanel = uipanel(app.UIFigure);
            app.XAxisPanel.Title = 'X-Axis';
            app.Button_1 = uibutton(app.XAxisPanel, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.XAxisPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.XAxisPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.XAxisPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.XAxisPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.GripperPanel = uipanel(app.UIFigure);
            app.GripperPanel.Title = 'Gripper';
            app.GripperOpenButton = uibutton(app.GripperPanel, 'push');
            app.GripperOpenButton.ButtonPushedFcn = createCallbackFcn(app, @GripperOpenButtonPushed, true);
            app.GripperOpenButton.Text = 'Gripper Open';
            app.GripperCloseButton = uibutton(app.GripperPanel, 'push');
            app.GripperCloseButton.ButtonPushedFcn = createCallbackFcn(app, @GripperCloseButtonPushed, true);
            app.GripperCloseButton.Text = 'Gripper Close';
            app.MovePlatePanel = uipanel(app.UIFigure);
            app.MovePlatePanel.Title = 'Move Plate';
            app.MoveButton = uibutton(app.MovePlatePanel, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.FromDropDownLabel = uilabel(app.MovePlatePanel);
            app.FromDropDownLabel.Text = 'From:';
            app.FromDropDown = uidropdown(app.MovePlatePanel);
            app.FromDropDown.Items = {'1', '2', '3', '4', '5'};
            app.FromDropDown.Value = '1';
            app.ToDropDownLabel = uilabel(app.MovePlatePanel);
            app.ToDropDownLabel.Text = 'To:';
            app.ToDropDown = uidropdown(app.MovePlatePanel);
            app.ToDropDown.Items = {'1', '2', '3', '4', '5'};
            app.ToDropDown.Value = '1';
            app.PlateLoaderResponsesPanel = uipanel(app.UIFigure);
            app.PlateLoaderResponsesPanel.Title = 'Plate Loader Responses';
            app.StatusLabel = uilabel(app.PlateLoaderResponsesPanel);
            app.StatusLabel.Text = 'Status...';
            app.GetStatusButton = uibutton(app.PlateLoaderResponsesPanel, 'push');
            app.GetStatusButton.ButtonPushedFcn = createCallbackFcn(app, @GetStatusButtonPushed, true);
            app.GetStatusButton.Text = 'Get Status';
            app.SetTimeDelaysPanel = uipanel(app.UIFigure);
            app.SetTimeDelaysPanel.Title = 'Set Time Delays';
            app.TimeTable = uitable(app.SetTimeDelaysPanel);
            app.TimeTable.ColumnName = '';
            app.TimeTable.RowName = {};
            app.TimeTable.ColumnEditable = true;
            app.SetTimeDelaysButton = uibutton(app.SetTimeDelaysPanel, 'push');
            app.SetTimeDelaysButton.ButtonPushedFcn = createCallbackFcn(app, @SetTimeDelaysButtonPushed, true);
            app.SetTimeDelaysButton.Text = 'Set TimeDelays';
            app.ResetTimeDelaysButton = uibutton(app.SetTimeDelaysPanel, 'push');
            app.ResetTimeDelaysButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTimeDelaysButtonPushed, true);
            app.ResetTimeDelaysButton.Text = 'Reset TimeDelays';
            app.GripperImage = uiimage(app.UIFigure);
            app.GripperImage.ImageSource = 'gripper_open_no_plate.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HTML_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end